classdef interface_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceREDv20UIFigure  matlab.ui.Figure
        Mapa2DPanel              matlab.ui.container.Panel
        Mapa3DPanel              matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        Lamp                     matlab.ui.control.Lamp
        Switch                   matlab.ui.control.ToggleSwitch
        Image                    matlab.ui.control.Image
        Interfacev20Label        matlab.ui.control.Label
    end


    properties (Access = private)
        gx = 0
        gg = 0
        initial_coordinates=[38.736789 -9.137551 ];
        coords=[38.736789 -9.137551 ];
        alt=5;
        t=0;
        go= false
    end
    
    methods (Access = private)
        
        function display_map(app)
                while true
                    drawnow %to update button status
                    if app.go == true
                       pause(1) %to simulate the rate of data received
                       x=[0.0001 0.0001];
                
                       %Update trajectory
                       hold(app.gx,"on")
                       geoplot(app.gx,[app.coords(1)+x(1) app.coords(1)], [app.coords(2)+x(2) app.coords(2)], 'Color','b', 'LineWidth', 2);
                       app.coords(1)= app.coords(1)+ x(1);
                       app.coords(2)= app.coords(2)+ x(2); 
                       app.t=app.t+1; %disp(t);
                
                       %Add new data to the table
                       oldData = get(app.UITable,'Data');
                       newRow={app.coords(1), app.coords(2), app.alt, app.t};
                       newData = [oldData; newRow];
                       set(app.UITable,'Data',newData);
                    end
                end
            end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create Table
            app.UITable.Data = {app.initial_coordinates(1), app.initial_coordinates(2), app.alt, app.t};
              
            %Create Maps
            app.gx=geoaxes(app.Mapa2DPanel,"Basemap","satellite"); 
            app.gg = geoglobe(app.Mapa3DPanel); %only availabe in MatLab2020a
            app.gx.InnerPosition = app.gx.OuterPosition;
            app.gg.Position = [0 0 1 1];
            
                %Center map
            app.gx.MapCenter = app.initial_coordinates;
            app.gx.ZoomLevel = 16;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.Lamp.Color = 'green';
                    app.go= true;
                    display_map(app)
                case 'Off'
                    app.Lamp.Color  = 'red';
                    app.go= false;
                    display_map(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceREDv20UIFigure = uifigure('Visible', 'off');
            app.InterfaceREDv20UIFigure.Name = 'Interface RED v2.0';
            app.Mapa2DPanel = uipanel(app.InterfaceREDv20UIFigure);
            app.Mapa2DPanel.Title = 'Mapa 2D';
            app.Mapa3DPanel = uipanel(app.InterfaceREDv20UIFigure);
            app.Mapa3DPanel.Title = 'Mapa 3D';
            app.UITable = uitable(app.InterfaceREDv20UIFigure);
            app.UITable.ColumnName = {'Latitude'; 'Longitude'; 'Altitude'; 'Tempo'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.InterfaceREDv20UIFigure);
            app.Switch = uiswitch(app.InterfaceREDv20UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Image = uiimage(app.InterfaceREDv20UIFigure);
            app.Image.ImageSource = 'RED.png';
            app.Interfacev20Label = uilabel(app.InterfaceREDv20UIFigure);
            app.Interfacev20Label.Text = 'Interface v2.0';
            % Show the figure after all components are created
            app.InterfaceREDv20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceREDv20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceREDv20UIFigure)
        end
    end
end